classdef cw_visual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label        matlab.ui.control.Label
        ClearButton  matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
            cam             % Webcam object
            isStreaming     % Boolean to control streaming
            newnet          % Property to store the face classification network
            gestureNet1     % Property to store the gesture recognition network
            newGestureNet
            faceDetector; pointTracker; videoPlayer; numPts; 
            people; x; y; height; width;
            oldPoints;
            bboxPoints;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the networks
            app.newnet = load('newnet.mat').newnet;
            app.gestureNet1 = load('newGestureNet.mat').newGestureNet;
            app.faceDetector = vision.CascadeObjectDetector('FrontalFaceCART','MinSize',[150,150]);
            app.cam = webcam; % This initializes the default webcam
            app.isStreaming = false;
            app.pointTracker = vision.PointTracker('MaxBidirectionalError', 2);
            % Initialize the webcam
            videoFrame = snapshot(app.cam);
            frameSize = size(videoFrame);
            % Create the video player object.
            app.videoPlayer = vision.VideoPlayer('Position', [100 100 [frameSize(2), frameSize(1)]+30]);
            app.numPts = 0;
            app.people = {'Jerry', 'Madonna', 'Mindy', 'Ben Afflek','Elton John','Sreedhar','Sreedhar-Happy','Sreedhar-Sad'};  % cell type
            app.x=300;
            app.y=300;
            app.height=500;
            app.width=500;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             % Toggle streaming on/off
            app.isStreaming = ~app.isStreaming;
            % Start streaming if not already active
            if app.isStreaming
                app.StartButton.Text = 'Pause';
                app.ClearButton.Enable = "off";
                app.Label.Text = 'Camera is activating and Capturing the Image';
                while app.isStreaming
                    % Capture a frame from the webcam
                    videoFrame = snapshot(app.cam);
                    videoFrameGray = rgb2gray(videoFrame);
                    feedbackstr = 'No Faces detected';
                    % Initialize IFaces with the current frame in case no annotations are added
                    % IFaces = videoFrame;
                    %-----  Start
                    if app.numPts < 10
                        bbox = app.faceDetector.step(videoFrameGray);
                        if ~isempty(bbox)
                            % Find corner points inside the detected region.
                            points = detectMinEigenFeatures(videoFrameGray, 'ROI', bbox(1, :));
                            % Re-initialize the point tracker.
                            xyPoints = points.Location;
                            app.numPts = size(xyPoints,1);
                            release(app.pointTracker);
                            initialize(app.pointTracker, xyPoints, videoFrameGray);
                            % Save a copy of the points.
                            app.oldPoints = xyPoints;
                            app.bboxPoints = bbox2points(bbox(1, :));
                            bboxPolygon = reshape(app.bboxPoints', 1, []);
                            % Display a bounding box around the detected face.
                            videoFrame = insertShape(videoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);
                            % Display detected corners.
                            videoFrame = insertMarker(videoFrame, xyPoints, '+', 'Color', 'white');
                        end
                    else
                        % Tracking mode.
                        [xyPoints, isFound] = step(app.pointTracker, videoFrameGray);
                        visiblePoints = xyPoints(isFound, :);
                        oldInliers = app.oldPoints(isFound, :);
                        app.numPts = size(visiblePoints, 1);
                        if app.numPts >= 10
                            % Estimate the geometric transformation between the old points
                            % and the new points.
                            [xform, ~, visiblePoints] = estimateGeometricTransform(...
                                oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
                            % Apply the transformation to the bounding box.
                            app.bboxPoints = transformPointsForward(xform, app.bboxPoints);
                            % Convert the box corners into the [x1 y1 x2 y2 x3 y3 x4 y4]
                            % format required by insertShape.
                            bboxPolygon = reshape(app.bboxPoints', 1, []);
                            % Added and Commented
                            % imwrite(videoFrame,[ 'C:\Users\desmond chin\OneDrive\Resources\Photos\',str,'\',int2str(i), '.jpg']); 
                            [img,face] = cropface(videoFrame);
                            % figure(3);imshow(img)
                            % face value is 1 when it detects face in image or 0
                            if face == 1
                               img = imresize(img,[227 227]);
                               predict = classify(app.newnet,img);
                                if predict==app.people{1}
                                  % fprintf('The face detected is %s\r',people{1});
                                  % title(people{1});
                                  feedbackstr = app.people{1};
                                elseif  predict==app.people{2}
                                  % fprintf('The face detected is %s\r',people{2});
                                  % title(people{2});
                                  feedbackstr = app.people{2};
                                elseif  predict==app.people{3}
                                  % fprintf('The face detected is %s\r',people{3});
                                  % title(people{3});
                                  feedbackstr = app.people{3};
                                elseif  predict==app.people{4}
                                  % fprintf('The face detected is %s\r',people{4});
                                  % title(people{4});
                                  feedbackstr = app.people{4};
                                elseif  predict==app.people{5}
                                  % fprintf('The face detected is %s\r',people{4});
                                  % title(people{4});
                                  feedbackstr = app.people{5};
                                elseif  predict==app.people{6}
                                  % fprintf('The face detected is %s\r',people{4});
                                  % title(people{4});
                                  feedbackstr = app.people{6};
                                elseif  predict==app.people{7}
                                  % fprintf('The face detected is %s\r',people{4});
                                  % title(people{4});
                                  feedbackstr = app.people{7};
                                elseif  predict==app.people{8}
                                  % fprintf('The face detected is %s\r',people{4});
                                  % title(people{4});
                                  feedbackstr = app.people{8};
                                end	 
                            else 
                               % disp("No Faces detected\r")
                               % title('No Faces detected');
                               feedbackstr = 'No Faces detected';
                            end
                            % End of Added ad Commented
                            % Display a bounding box around the face being tracked.
                            videoFrame = insertShape(videoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);                
                            % Display tracked points.
                            videoFrame = insertMarker(videoFrame, visiblePoints, '+', 'Color', 'white');                
                            position = [1000 100]; % [x y] coordinates of the bottom-left corner of the text box
                            text_str = feedbackstr; % The text you want to insert
                            videoFrame = insertText(videoFrame, position, text_str, 'FontSize', 24, 'BoxColor', 'red', 'BoxOpacity', 0.4, 'TextColor', 'white');
                            % Reset the points.
                            app.oldPoints = visiblePoints;
                            setPoints(app.pointTracker, app.oldPoints);
                        end            
                    end
                    bboxes=[app.x app.y app.height app.width];
                    IFaces = insertObjectAnnotation(videoFrame,'rectangle',bboxes,'Processing Area');
                    es=imcrop(videoFrame,bboxes);
                    es=imresize(es,[227 227]);
                    label=classify(app.gestureNet1,es);
                    % imshow(IFaces);
                    % imshow(e)
                    IFaces = insertText(IFaces, [140 300],char(label), 'FontSize', 24, 'BoxColor', 'blue', 'BoxOpacity', 0.4, 'TextColor', 'white');
                    % Display the annotated video frame using the video player object.
                    % step(videoPlayer, videoFrame);
                    % step(videoPlayer, IFaces);    
                    %-----  End
                    % Display the frame in UIAxes
                    imshow(IFaces, 'Parent', app.UIAxes); % ChatGPT, why do I have error here?
                    % Pause briefly to avoid overloading
                    pause(0.01); % Adjust as necessary for smoother display
                end
            else
                app.StartButton.Text = 'Play';
                app.ClearButton.Enable = "on";
                app.Label.Text = '🛑 Detection stopped';
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
                % Release the webcam if it's in use
            if ~isempty(app.cam)
                clear app.cam;
            end
            if app.isStreaming
                % placeholder reserved
            else
                cla(app.UIAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Camera View')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cw_visual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end